classdef HybridArSA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HybridArabicTextSteganographyAlgorithmUIFigure  matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        CoverTextLabel               matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        SecretTextLabel              matlab.ui.control.Label
        TextArea_2                   matlab.ui.control.TextArea
        TextArea_3                   matlab.ui.control.TextArea
        SteganoTextLabel             matlab.ui.control.Label
        CopythesteganotexttogetthesecrettextButton  matlab.ui.control.Button
        HideButton                   matlab.ui.control.Button
        UnhideButton                 matlab.ui.control.Button
        ClearallButton               matlab.ui.control.Button
        ExitButton                   matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        ResultsPanel                 matlab.ui.container.Panel
        MethodsButtonGroup           matlab.ui.container.ButtonGroup
        DotsButton                   matlab.ui.control.RadioButton
        KashidaLetterPointsButton    matlab.ui.control.RadioButton
        Kashida2BitsButton           matlab.ui.control.RadioButton
        CapacityRatioEditFieldLabel  matlab.ui.control.Label
        CapacityRatioEditField       matlab.ui.control.EditField
        CoverTextSizeBytesEditFieldLabel  matlab.ui.control.Label
        CoverTextSizeBytesEditField  matlab.ui.control.EditField
        AmountofHiddenBitsEditFieldLabel  matlab.ui.control.Label
        AmountofHiddenBitsEditField  matlab.ui.control.EditField
        StatusLampLabel              matlab.ui.control.Label
        StatusLamp                   matlab.ui.control.Lamp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HideButton
        function HideButtonPushed(app, event)
            global mappingtable Original_characters
            app.CoverTextLabel.Text= 'Cover Text';
            app.SecretTextLabel.Text= 'Secret Text';
            app.SteganoTextLabel.Text= 'Stegano Text';
            
            
            %             secrettext = char(app.TextArea_2.Value);
            
            [newcovertext, secrettext] = PrepareCoverText(app.TextArea.Value, app.TextArea_2.Value);
            %             covertext = char(app.TextArea.Value);
            Original_characters = length(newcovertext);
            
            app.StatusLamp.Color = 'red';
            pause(0.1)
            if strcmp(app.MethodsButtonGroup.SelectedObject.Text, 'Dots')
                [steganotext,mappingtable, Stegobitsused,Secretbits] = Dot_Kashida(newcovertext, secrettext);
                
            elseif strcmp(app.MethodsButtonGroup.SelectedObject.Text, 'Kashida Letter Points')
                [steganotext,mappingtable, Stegobitsused,Secretbits] = KashidaLetterPoints(newcovertext, secrettext);
                
                
            elseif strcmp(app.MethodsButtonGroup.SelectedObject.Text, 'Kashida 2 Bits')
                [steganotext,mappingtable, Stegobitsused,Secretbits] = Kashida2Bits(newcovertext, secrettext);
            end
            app.StatusLamp.Color = 'green';
            
            app.TextArea_3.Value = steganotext;
            coverbyts = length(uint16(newcovertext));
            app.CoverTextSizeBytesEditField.Value=num2str(coverbyts);
            
            spaceNbr = length(find(uint16(newcovertext)==32));


            app.AmountofHiddenBitsEditField.Value=num2str(double(Stegobitsused));
            
            capacityratio = double(Stegobitsused)/double(coverbyts*8);
            capacityratio  =  capacityratio*100;
            app.CapacityRatioEditField.Value=num2str(capacityratio);


        end

        % Button pushed function: 
        % CopythesteganotexttogetthesecrettextButton
        function CopythesteganotexttogetthesecrettextButtonPushed(app, event)
            clipboard('copy',char(app.TextArea_3.Value));
            app.CoverTextLabel.Text='Stegano Text';
            app.SecretTextLabel.Text= 'Cover Text';
            app.SteganoTextLabel.Text='Secret Text';
            
            app.TextArea.Value =  char(app.TextArea_3.Value);
            app.TextArea_2.Value = '';
            app.TextArea_3.Value = '';
            
        end

        % Button pushed function: ClearallButton
        function ClearallButtonPushed(app, event)
            app.TextArea.Value = '';
            app.TextArea_2.Value = '';
            app.TextArea_3.Value = '';
            
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            close(app.HybridArabicTextSteganographyAlgorithmUIFigure);
        end

        % Button pushed function: UnhideButton
        function UnhideButtonPushed(app, event)
            global mappingtable Original_characters
            
            
            steganotext = char(app.TextArea.Value);


          if strcmp(app.MethodsButtonGroup.SelectedObject.Text, 'Dots')
                [covertext, originaltext] = DecodeSteganoPart3(steganotext,mappingtable);
            elseif strcmp(app.MethodsButtonGroup.SelectedObject.Text, 'Kashida Letter Points')
                [covertext, originaltext] = DecodeSteganoPart4(steganotext,mappingtable);
                
            elseif strcmp(app.MethodsButtonGroup.SelectedObject.Text, 'Kashida 2 Bits')
                [covertext, originaltext] = DecodeSteganoPart5(steganotext,mappingtable);
            end
            app.TextArea_2.Value = covertext;
            app.TextArea_3.Value = originaltext;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HybridArabicTextSteganographyAlgorithmUIFigure = uifigure('Visible', 'off');
            app.HybridArabicTextSteganographyAlgorithmUIFigure.Name = 'Hybrid Arabic Text Steganography Algorithm';
            app.Panel = uipanel(app.HybridArabicTextSteganographyAlgorithmUIFigure);
            app.CoverTextLabel = uilabel(app.Panel);
            app.CoverTextLabel.Text = 'Cover Text';
            app.TextArea = uitextarea(app.Panel);
            app.SecretTextLabel = uilabel(app.Panel);
            app.SecretTextLabel.Text = 'Secret Text';
            app.TextArea_2 = uitextarea(app.Panel);
            app.TextArea_3 = uitextarea(app.Panel);
            app.SteganoTextLabel = uilabel(app.Panel);
            app.SteganoTextLabel.Text = 'Stegano Text';
            app.CopythesteganotexttogetthesecrettextButton = uibutton(app.HybridArabicTextSteganographyAlgorithmUIFigure, 'push');
            app.CopythesteganotexttogetthesecrettextButton.ButtonPushedFcn = createCallbackFcn(app, @CopythesteganotexttogetthesecrettextButtonPushed, true);
            app.CopythesteganotexttogetthesecrettextButton.Text = {'Copy the stegano'; ' text to get the '; 'secret text'};
            app.HideButton = uibutton(app.HybridArabicTextSteganographyAlgorithmUIFigure, 'push');
            app.HideButton.ButtonPushedFcn = createCallbackFcn(app, @HideButtonPushed, true);
            app.HideButton.Text = 'Hide';
            app.UnhideButton = uibutton(app.HybridArabicTextSteganographyAlgorithmUIFigure, 'push');
            app.UnhideButton.ButtonPushedFcn = createCallbackFcn(app, @UnhideButtonPushed, true);
            app.UnhideButton.Text = 'Unhide';
            app.ClearallButton = uibutton(app.HybridArabicTextSteganographyAlgorithmUIFigure, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
            app.ClearallButton.Text = 'Clear all';
            app.ExitButton = uibutton(app.HybridArabicTextSteganographyAlgorithmUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Image = uiimage(app.HybridArabicTextSteganographyAlgorithmUIFigure);
            app.Image.ImageSource = 'logo.PNG';
            app.ResultsPanel = uipanel(app.HybridArabicTextSteganographyAlgorithmUIFigure);
            app.ResultsPanel.Title = 'Results';
            app.MethodsButtonGroup = uibuttongroup(app.ResultsPanel);
            app.MethodsButtonGroup.Title = 'Methods:';
            app.DotsButton = uiradiobutton(app.MethodsButtonGroup);
            app.DotsButton.Text = 'Dots';
            app.DotsButton.Value = true;
            app.KashidaLetterPointsButton = uiradiobutton(app.MethodsButtonGroup);
            app.KashidaLetterPointsButton.Text = 'Kashida Letter Points';
            app.Kashida2BitsButton = uiradiobutton(app.MethodsButtonGroup);
            app.Kashida2BitsButton.Text = 'Kashida 2 Bits';
            app.CapacityRatioEditFieldLabel = uilabel(app.ResultsPanel);
            app.CapacityRatioEditFieldLabel.Text = 'Capacity Ratio (%)';
            app.CapacityRatioEditField = uieditfield(app.ResultsPanel, 'text');
            app.CoverTextSizeBytesEditFieldLabel = uilabel(app.ResultsPanel);
            app.CoverTextSizeBytesEditFieldLabel.Text = 'Cover Text Size (Bytes)';
            app.CoverTextSizeBytesEditField = uieditfield(app.ResultsPanel, 'text');
            app.AmountofHiddenBitsEditFieldLabel = uilabel(app.ResultsPanel);
            app.AmountofHiddenBitsEditFieldLabel.Text = 'Amount of Hidden Bits';
            app.AmountofHiddenBitsEditField = uieditfield(app.ResultsPanel, 'text');
            app.StatusLampLabel = uilabel(app.ResultsPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.ResultsPanel);
            % Show the figure after all components are created
            app.HybridArabicTextSteganographyAlgorithmUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HybridArSA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HybridArabicTextSteganographyAlgorithmUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HybridArabicTextSteganographyAlgorithmUIFigure)
        end
    end
end